classdef cubesolver < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TabGroup                 matlab.ui.container.TabGroup
        ScanTab                  matlab.ui.container.Tab
        webview                  matlab.ui.control.UIAxes
        TAKESNAPSHOTButton       matlab.ui.control.Button
        preview                  matlab.ui.control.UIAxes
        SolveTab                 matlab.ui.container.Tab
        NEXTMOVEButton           matlab.ui.control.Button
        MOVESListBoxLabel        matlab.ui.control.Label
        MOVESListBox             matlab.ui.control.ListBox
        cube                     matlab.ui.control.UIAxes
        STATUSTextAreaLabel      matlab.ui.control.Label
        STATUSTextArea           matlab.ui.control.TextArea
        TOTALMOVESTextAreaLabel  matlab.ui.control.Label
        TOTALMOVESTextArea       matlab.ui.control.TextArea
        UNDOMOVEButton           matlab.ui.control.Button
        RESCANButton             matlab.ui.control.Button
        SCRAMBLEButton           matlab.ui.control.Button
    end

    
    properties (Access = public)
        Property % Description
        sol 
        faces
        sol_idx
        face_to_detect
        cam = webcam(1);
        frame = [350, 100, 550, 550];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.cube.Visible = 'off';
            app.webview.Visible = 'off';
            app.preview.Visible = 'off';
            app.sol = {};
            app.faces = zeros(6, 9);
            app.sol_idx = 1;
            app.face_to_detect = 1; 
            % Get the handle to the image in the axes.
            hImage = image(app.webview, zeros(320, 240,'uint8'));
            % Reset image magnification. Required if you ever displayed an image
            % in the axes that was not the same size as your webcam image.
            axis(app.webview, 'image')
    
            % Turn on the live video.
    
            preview(app.cam, hImage);
            rectangle(app.webview, 'Position', app.frame, 'EdgeColor', ...
                      'r','LineWidth', 5);
        end

        % Button pushed function: NEXTMOVEButton
        function NEXTMOVEButtonPushed(app, event)
            move = app.sol(app.sol_idx);
            app.faces = execute_algorithm(app.faces, move, 1, app.cube);
            app.sol_idx = app.sol_idx + 1;
            app.MOVESListBox.Items = app.sol(app.sol_idx:end);
        end

        % Button pushed function: UNDOMOVEButton
        function UNDOMOVEButtonPushed(app, event)
            last_move = app.sol{app.sol_idx - 1};
            if last_move(end) == 'p' && last_move(end-1) ~= 'u'
                anti_move = {last_move(1:end-1)};
            else
                anti_move = {[last_move 'p']};
            end
            app.faces = execute_algorithm(app.faces, anti_move, 1, app.cube);
            app.sol_idx = app.sol_idx - 1;
            app.MOVESListBox.Items = app.sol(app.sol_idx:end);
        end

        % Button pushed function: RESCANButton
        function RESCANButtonPushed(app, event)
            startupFcn(app);
        end

        % Button pushed function: TAKESNAPSHOTButton
        function TAKESNAPSHOTButtonPushed(app, event)
            if app.face_to_detect < 6
                [app.faces, rel] = detect_face(app.faces, app.face_to_detect,...
                                               app.frame, app.preview, app.cam);
                if rel
                    app.face_to_detect = app.face_to_detect + 1;
                end
            elseif app.face_to_detect == 6
                [app.faces, rel] = init(app.cam, app.cube, app.preview, ...
                                        1, app.face_to_detect, app.faces, app.frame);
                if rel    % run all 
                    app.face_to_detect = app.face_to_detect + 1;
                    [out, number_moves, app.sol] = main(app.faces);
                    app.STATUSTextArea.Value = out;
                    app.TOTALMOVESTextArea.Value = num2str(number_moves);
                    app.MOVESListBox.Items = app.sol(1:end);
                end
            end
        end

        % Button pushed function: SCRAMBLEButton
        function SCRAMBLEButtonPushed(app, event)
            [app.faces, ~] = init(app.cam, app.cube, app.preview, ...
                                  0, app.face_to_detect, app.faces, app.frame);
                              
            app.face_to_detect = app.face_to_detect + 1;
            [out, number_moves, app.sol] = main(app.faces);
            app.STATUSTextArea.Value = out;
            app.TOTALMOVESTextArea.Value = num2str(number_moves);
            app.MOVESListBox.Items = app.sol(1:end);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ScanTab = uitab(app.TabGroup);
            app.ScanTab.Title = 'Scan';
            app.webview = uiaxes(app.ScanTab);
            title(app.webview, 'Title')
            xlabel(app.webview, 'X')
            ylabel(app.webview, 'Y')
            app.TAKESNAPSHOTButton = uibutton(app.ScanTab, 'push');
            app.TAKESNAPSHOTButton.ButtonPushedFcn = createCallbackFcn(app, @TAKESNAPSHOTButtonPushed, true);
            app.TAKESNAPSHOTButton.Text = 'TAKE SNAPSHOT';
            app.preview = uiaxes(app.ScanTab);
            title(app.preview, '')
            xlabel(app.preview, '')
            ylabel(app.preview, '')
            app.SolveTab = uitab(app.TabGroup);
            app.SolveTab.Title = 'Solve';
            app.NEXTMOVEButton = uibutton(app.SolveTab, 'push');
            app.NEXTMOVEButton.ButtonPushedFcn = createCallbackFcn(app, @NEXTMOVEButtonPushed, true);
            app.NEXTMOVEButton.Text = 'NEXT MOVE!';
            app.MOVESListBoxLabel = uilabel(app.SolveTab);
            app.MOVESListBoxLabel.Text = 'MOVES';
            app.MOVESListBox = uilistbox(app.SolveTab);
            app.cube = uiaxes(app.SolveTab);
            title(app.cube, 'Title')
            xlabel(app.cube, 'X')
            ylabel(app.cube, 'Y')
            app.STATUSTextAreaLabel = uilabel(app.SolveTab);
            app.STATUSTextAreaLabel.Text = 'STATUS';
            app.STATUSTextArea = uitextarea(app.SolveTab);
            app.STATUSTextArea.Value = {'UNRESOLVED'};
            app.TOTALMOVESTextAreaLabel = uilabel(app.SolveTab);
            app.TOTALMOVESTextAreaLabel.Text = 'TOTAL MOVES';
            app.TOTALMOVESTextArea = uitextarea(app.SolveTab);
            app.TOTALMOVESTextArea.Value = {'NONE'};
            app.UNDOMOVEButton = uibutton(app.SolveTab, 'push');
            app.UNDOMOVEButton.ButtonPushedFcn = createCallbackFcn(app, @UNDOMOVEButtonPushed, true);
            app.UNDOMOVEButton.Text = 'UNDO MOVE';
            app.RESCANButton = uibutton(app.SolveTab, 'push');
            app.RESCANButton.ButtonPushedFcn = createCallbackFcn(app, @RESCANButtonPushed, true);
            app.RESCANButton.Text = 'RE-SCAN';
            app.SCRAMBLEButton = uibutton(app.SolveTab, 'push');
            app.SCRAMBLEButton.ButtonPushedFcn = createCallbackFcn(app, @SCRAMBLEButtonPushed, true);
            app.SCRAMBLEButton.Text = 'SCRAMBLE';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cubesolver

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end